classdef app_robotf_mec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Servo1SliderLabel        matlab.ui.control.Label
        Servo1Slider             matlab.ui.control.Slider
        VALLabel                 matlab.ui.control.Label
        Servo2SliderLabel        matlab.ui.control.Label
        Servo2Slider             matlab.ui.control.Slider
        VAL2Label                matlab.ui.control.Label
        VAL3Label                matlab.ui.control.Label
        ENVIARButton             matlab.ui.control.Button
        ENVIARButton_2           matlab.ui.control.Button
        JacobianoLabel           matlab.ui.control.Label
        CinematicadirectaLabel   matlab.ui.control.Label
        XLabel                   matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        ZLabel                   matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        THETA1EditFieldLabel     matlab.ui.control.Label
        THETA1EditField          matlab.ui.control.EditField
        CalcularCDButton         matlab.ui.control.Button
        CalcularCIButton         matlab.ui.control.Button
        centrodemasapieza2Label  matlab.ui.control.Label
        centrodemasapieza3Label  matlab.ui.control.Label
        centrodemasapieza4Label  matlab.ui.control.Label
        Label_19                 matlab.ui.control.Label
        Label_20                 matlab.ui.control.Label
        Label_21                 matlab.ui.control.Label
        Label_22                 matlab.ui.control.Label
        InicioLabel              matlab.ui.control.Label
        LargoLabel               matlab.ui.control.Label
        AnchoLabel               matlab.ui.control.Label
        X_val                    matlab.ui.control.NumericEditField
        EditField_20             matlab.ui.control.NumericEditField
        EditField_21             matlab.ui.control.NumericEditField
        RectnguloLabel           matlab.ui.control.Label
        LinealLabel              matlab.ui.control.Label
        EditField_22             matlab.ui.control.NumericEditField
        ToLabel_2                matlab.ui.control.Label
        TfLabel_3                matlab.ui.control.Label
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
        EditField_30             matlab.ui.control.NumericEditField
        EditField_29             matlab.ui.control.NumericEditField
        TfLabel                  matlab.ui.control.Label
        TfLabel_2                matlab.ui.control.Label
        EditField_23             matlab.ui.control.NumericEditField
        EditField_28             matlab.ui.control.NumericEditField
        ToLabel                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        QuinticoLabel            matlab.ui.control.Label
        Servo3SliderLabel        matlab.ui.control.Label
        EditField_25             matlab.ui.control.NumericEditField
        TfLabel_4                matlab.ui.control.Label
        LSPBLabel                matlab.ui.control.Label
        EditField_26             matlab.ui.control.NumericEditField
        GenerarcbicoButton       matlab.ui.control.Button
        GenerarLSPBButton        matlab.ui.control.Button
        GenerarQuinticoButton    matlab.ui.control.Button
        GenerarlinealButton      matlab.ui.control.Button
        Y_val                    matlab.ui.control.NumericEditField
        Z_val                    matlab.ui.control.NumericEditField
        ToLabel_3                matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        CbicoLabel               matlab.ui.control.Label
        Servo3Slider             matlab.ui.control.Slider
        Label_4                  matlab.ui.control.Label
        THETA2EditFieldLabel     matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EditField_3              matlab.ui.control.EditField
        EditField_6              matlab.ui.control.EditField
        THETA3Label              matlab.ui.control.Label
        EditField_5              matlab.ui.control.EditField
        EditField_9              matlab.ui.control.EditField
        THETA2EditField          matlab.ui.control.EditField
        EditField_8              matlab.ui.control.EditField
        EditField_12             matlab.ui.control.EditField
        EditField_4              matlab.ui.control.EditField
        EditField_11             matlab.ui.control.EditField
        EditField_15             matlab.ui.control.EditField
        EditField_7              matlab.ui.control.EditField
        EditField_14             matlab.ui.control.EditField
        EditField_18             matlab.ui.control.EditField
        EditField_10             matlab.ui.control.EditField
        EditField_17             matlab.ui.control.EditField
        ConstantesLabel          matlab.ui.control.Label
        IxxLabel                 matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Pieza2Label              matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        EditField_13             matlab.ui.control.EditField
        Label_10                 matlab.ui.control.Label
        IyyLabel                 matlab.ui.control.Label
        IzzLabel_2               matlab.ui.control.Label
        IzzLabel                 matlab.ui.control.Label
        Label_14                 matlab.ui.control.Label
        EditField_16             matlab.ui.control.EditField
        IyyLabel_2               matlab.ui.control.Label
        THETA2Label              matlab.ui.control.Label
        Label_13                 matlab.ui.control.Label
        THETA3EditFieldLabel     matlab.ui.control.Label
        IzzLabel_3               matlab.ui.control.Label
        Label_12                 matlab.ui.control.Label
        Label_18                 matlab.ui.control.Label
        THETA1Label              matlab.ui.control.Label
        IyyLabel_3               matlab.ui.control.Label
        Pieza3Label              matlab.ui.control.Label
        Label_17                 matlab.ui.control.Label
        THETA3EditField          matlab.ui.control.EditField
        masaLabel                matlab.ui.control.Label
        gLabel                   matlab.ui.control.Label
        gLabel_2                 matlab.ui.control.Label
        Label_16                 matlab.ui.control.Label
        gLabel_3                 matlab.ui.control.Label
        masaLabel_2              matlab.ui.control.Label
        masaLabel_3              matlab.ui.control.Label
        Label_11                 matlab.ui.control.Label
        Pieza4Label              matlab.ui.control.Label
        CinematicainversaLabel   matlab.ui.control.Label
        Label_15                 matlab.ui.control.Label
        IxxLabel_2               matlab.ui.control.Label
        IxxLabel_3               matlab.ui.control.Label
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.EditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.EditField
        ZEditFieldLabel          matlab.ui.control.Label
        ZEditField               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        q1 = '0';
        q2 = '0';
        q3 = '0';
        value;
        value2;
        value3;
        RobotArticulado= SerialLink([
            Revolute('alpha', pi/2, 'a', 0, 'd', 4);
            Revolute('alpha', 0, 'a',12.5, 'd', 0);
            Revolute('alpha', 0, 'a', 9, 'd', 0)]); % Description


    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%FUNCIONES%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = public)

        function [t,q, qp, qpp] = Interpol_lin(app, to, tf, qo, qf, dt)
            
            A = [to, 1; tf, 1];
            B = [qo,; qf];
            t = to:dt:tf;
            qp = 0:length(t)-1;
            qpp = 0:length(t);
            X = inv(A)*B;
            q = X(1)*t+X(2); 
            qp = X(1)*ones(1, length(q));
            qpp = 0*ones(1, length(q));

        end
        
        function [t, q, qp, qpp] = Interpol_cub(app, to, tf, qo, qf, dt, vo, vf)
            A = [to.^3, to.^2, to, 1; tf.^3, tf.^2, tf, 1; 3*to.^2, 2*to, 1, 0; 3*tf.^2, 2*tf, 1, 0];
            B = [qo; qf; vo; vf];
            t = to:dt:tf;
            X = inv(A)*B;
            q = X(1)*t.^3 + X(2)*t.^2 + X(3)*t + X(4);
            qp = 3*X(1)*t.^2 + 2*X(2)*t + X(3);
            qpp = 6*X(1)*t + 2*X(2);
        end
        
        function [q, t] = LSPB(app,qo,qf,tf, dt)
            if qo == qf
                qmax = 0;
                tb = 0;
            else
                qmax = (abs(qf-qo)) / 4;
                
                tb = (-abs(qo - qf) + (qmax*tf)) / qmax;
                %t = 0:dt:tb;
            end
            
            t1 = 0:dt:tb;
            t2 = tb+dt:dt:tf-tb;
            t3 = tf-tb+dt:dt:tf;
            
            if qo == qf
                q1 = qo*ones(1,length(t1));
                
                q2 = qo*ones(1,length(t2));
                
                q3 = qo*ones(1,length(t3));
            end
            
            if qo < qf
                q1 = qo + (1/2*(qmax/tb)*t1.^2);
                
                q2 = qo - (1/2 * tb * qmax) + (qmax*t2);
                
                q3 = qf - (1/2*(qmax/tb) * (t3-tf).^2);
            end
            
            if qo > qf
                q1 = qo - (1/2*(qmax/tb)*t1.^2);
                
                q2 = qo + (1/2 * tb * qmax) - (qmax*t2);
                
                q3 = qf + (1/2*(qmax/tb) * (t3-tf).^2);
            end
            
            qq1 = (qmax/tb)*t1;
            qq2 = (qmax .* t2) ./ t2;
            qq3 = (qmax/tb) * (tf-t3);
            
            t = [t1,t2,t3];
            q = [q1,q2,q3];
            qq = [qq1,qq2,qq3];
            
        end
    
        
        function [t, q, qp, qpp] = Interpol_quin(app, to, tf, qo, qf, dt, vo, vf,ao,af)
                    
            A=[to.^5,to.^4,to.^3,to.^2,to,1;...
                tf.^5,tf.^4,tf.^3,tf.^2,tf,1;...
                5*to.^4,4*to.^3,3*to.^2,2*to,1,0;...
                5*tf.^4,4*tf.^3,3*tf.^2,2*tf,1,0;...
                20*to.^3,12*to.^2,6*to,2,0,0;...
                20*tf.^3,12*tf.^2,6*tf,2,0,0;];
            B=[qo;qf;vo;vf;ao;af];
            t=to:dt:tf;
            X=inv(A)*B;
            q=X(1)*t.^5 +X(2)*t.^4 +X(3)*t.^3 + X(4)*t.^2 + X(5)*t +X(6);
            qp = (5*X(1)*(t.^4)) + (4*X(2)*(t.^3)) + (3*X(3)*(t.^2)) + (2*X(4)*(t.^1)) + X(5);
            qpp = (20*X(1)*t.^3) + (12*X(2)*t.^2) + (6*X(3)*t) + (2*X(4));
        end
        
        function [q] = CalculoCI(app, Px, Py, Pz)
            d1=4;
            %d5=72;
            a2=12.5;
            a3=9;
            Pz2=Pz-d1;
            t1_inv=atand(Py/Px);
            t3_inv=-acosd(((Pz2^2+Px^2+Py^2)-((a2^2)+(a3^2)))/(2*a2*a3));
            t2_inv=(atand(Pz2/sqrt(Px^2+Py^2)))-(atand((a3*sind(t3_inv))/(a2+(a3*cosd(t3_inv)))));
            
            q = [t1_inv, t2_inv, t3_inv];
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Servo1Slider
        function Servo1SliderValueChanged(app, event)
            value = num2str(get(app.Servo1Slider, 'Value'));
            val = cellstr(value);
            
            app.q1 = value;
            set(app.VALLabel, 'Text', val);
            
            %ploteo de robot
            RobotArticulado= SerialLink([
            Revolute('alpha', pi/2, 'a', 0, 'd', 4);
            Revolute('alpha', 0, 'a',12.5, 'd', 0);
            Revolute('alpha', 0, 'a', 9, 'd', 0)]); % Description
            
            
            j = jacob0(app.RobotArticulado, [str2num(app.q1),str2num(app.q2),str2num(app.q3)]);
            round(j, 4);
            app.EditField.Value = num2str(round(j(1,1), 2));
            app.EditField_2.Value = num2str(round(j(1,2), 2));
            app.EditField_3.Value = num2str(round(j(1,3), 2));
            app.EditField_4.Value = num2str(round(j(2,1), 2));
            app.EditField_5.Value = num2str(round(j(2,2), 2));
            app.EditField_6.Value = num2str(round(j(2,3), 2));
            app.EditField_7.Value = num2str(round(j(3,1), 2));
            app.EditField_8.Value = num2str(round(j(3,2), 2));
            app.EditField_9.Value = num2str(round(j(3,3), 2));
            app.EditField_10.Value = num2str(round(j(4,1), 2));
            app.EditField_11.Value = num2str(round(j(4,2), 2));
            app.EditField_12.Value = num2str(round(j(4,3), 2));
            app.EditField_13.Value = num2str(round(j(5,1), 2));
            app.EditField_14.Value = num2str(round(j(5,2), 2));
            app.EditField_15.Value = num2str(round(j(5,3), 2));
            app.EditField_16.Value = num2str(round(j(6,1), 2));
            app.EditField_17.Value = num2str(round(j(6,2), 2));
            app.EditField_18.Value = num2str(round(j(6,3), 2));
        end

        % Value changed function: Servo2Slider
        function Servo2SliderValueChanged(app, event)
            value2 = num2str(get(app.Servo2Slider, 'Value'));
            val2 = cellstr(value2)
            
            app.q2 = value2;
            set(app.VAL2Label, 'Text', val2);
            
%             app.RobotArticulado = SerialLink([
%             Revolute('alpha', pi/2, 'a', 0, 'd', 4);
%             Revolute('alpha', 0, 'a',12.5, 'd', 0);
%             Revolute('alpha', 0, 'a', 9, 'd', 0);
%             ])
            j = jacob0(app.RobotArticulado, [str2num(app.q1),str2num(app.q2),str2num(app.q3)]);
            round(j, 4);
            app.EditField.Value = num2str(round(j(1,1), 2));
            app.EditField_2.Value = num2str(round(j(1,2), 2));
            app.EditField_3.Value = num2str(round(j(1,3), 2));
            app.EditField_4.Value = num2str(round(j(2,1), 2));
            app.EditField_5.Value = num2str(round(j(2,2), 2));
            app.EditField_6.Value = num2str(round(j(2,3), 2));
            app.EditField_7.Value = num2str(round(j(3,1), 2));
            app.EditField_8.Value = num2str(round(j(3,2), 2));
            app.EditField_9.Value = num2str(round(j(3,3), 2));
            app.EditField_10.Value = num2str(round(j(4,1), 2));
            app.EditField_11.Value = num2str(round(j(4,2), 2));
            app.EditField_12.Value = num2str(round(j(4,3), 2));
            app.EditField_13.Value = num2str(round(j(5,1), 2));
            app.EditField_14.Value = num2str(round(j(5,2), 2));
            app.EditField_15.Value = num2str(round(j(5,3), 2));
            app.EditField_16.Value = num2str(round(j(6,1), 2));
            app.EditField_17.Value = num2str(round(j(6,2), 2));
            app.EditField_18.Value = num2str(round(j(6,3), 2));
            
        end

        % Value changed function: Servo3Slider
        function Servo3SliderValueChanged(app, event)
            value3 = num2str(get(app.Servo3Slider, 'Value'));
            val3 = cellstr(value3);
            app.q3 = value3;
            set(app.VAL3Label, 'Text', val3);
            
%             app.RobotArticulado = SerialLink([
%             Revolute('alpha', pi/2, 'a', 0, 'd', 4);
%             Revolute('alpha', 0, 'a',12.5, 'd', 0);
%             Revolute('alpha', 0, 'a', 9, 'd', 0);
%             ])
            j = jacob0(app.RobotArticulado, [str2num(app.q1),str2num(app.q2),str2num(app.q3)]);
            
            app.EditField.Value = num2str(round(j(1,1), 2));
            app.EditField_2.Value = num2str(round(j(1,2), 2));
            app.EditField_3.Value = num2str(round(j(1,3), 2));
            app.EditField_4.Value = num2str(round(j(2,1), 2));
            app.EditField_5.Value = num2str(round(j(2,2), 2));
            app.EditField_6.Value = num2str(round(j(2,3), 2));
            app.EditField_7.Value = num2str(round(j(3,1), 2));
            app.EditField_8.Value = num2str(round(j(3,2), 2));
            app.EditField_9.Value = num2str(round(j(3,3), 2));
            app.EditField_10.Value = num2str(round(j(4,1), 2));
            app.EditField_11.Value = num2str(round(j(4,2), 2));
            app.EditField_12.Value = num2str(round(j(4,3), 2));
            app.EditField_13.Value = num2str(round(j(5,1), 2));
            app.EditField_14.Value = num2str(round(j(5,2), 2));
            app.EditField_15.Value = num2str(round(j(5,3), 2));
            app.EditField_16.Value = num2str(round(j(6,1), 2));
            app.EditField_17.Value = num2str(round(j(6,2), 2));
            app.EditField_18.Value = num2str(round(j(6,3), 2));
        end

        % Button pushed function: ENVIARButton_2
        function ENVIARButton_2Pushed(app, event)
            ard = arduino('COM6', 'Mega2560', 'Libraries', 'Servo');
            Servo1 = servo(ard, 'D13');
            Servo2 = servo(ard, 'D11');
            Servo3 = servo(ard, 'D10');
            
            
            deg1 = (str2num(app.q1)+90)/180; % valores de 0 a 1
            deg2 = (str2num(app.q2))/180;
            deg3 = 1-((str2num(app.q3)+90)/180); % corrige el giro con 1 -
            
            
            %Ploteo de robot la funcion plot usa radianes
            A1 = app.RobotArticulado.links(1).A(str2num(app.q1));
            A2 = app.RobotArticulado.links(2).A(str2num(app.q2));
            A3 = app.RobotArticulado.links(3).A(str2num(app.q3));
            app.RobotArticulado.plot([deg2rad(str2num(app.q1)), deg2rad(str2num(app.q2)), deg2rad(str2num(app.q3))]);
            
            
            writePosition(Servo1, deg1);
            writePosition(Servo2, deg2);
            writePosition(Servo3, deg3);
            pause(1);
        end

        % Button pushed function: CalcularCDButton
        function CalcularCDButtonPushed(app, event)
            theta1 = str2num(app.THETA1EditField.Value);
            theta2 = str2num(app.THETA2EditField.Value);
            theta3 = str2num(app.THETA3EditField.Value);
            theta1 = deg2rad(theta1);
            theta2 = deg2rad(theta2);
            theta3 = deg2rad(theta3);
            
            %parametros fijos del robot
            d1 = 4;
            a2 = 12.5;
            a3 = 9;
            
            %fkine sirve para calculo de cinematica directa
            M=app.RobotArticulado.fkine([theta1,theta2,theta3]);
            
            X_dir= M.t(1);
            X_dir = round(X_dir, 2);
            X_dir = num2str(X_dir);
            app.Label.Text = X_dir;
            
            Y_dir = M.t(2);
            Y_dir = round(Y_dir, 2);
            Y_dir = num2str(Y_dir);
            app.Label_2.Text = Y_dir;
            
            Z_dir = M.t(3);
            Z_dir = round(Z_dir, 2);
            Z_dir = num2str(Z_dir);
            app.Label_3.Text =Z_dir;
        end

        % Button pushed function: CalcularCIButton
        function CalcularCIButtonPushed(app, event)
            X = str2num(app.XEditField.Value);
            Y = str2num(app.YEditField.Value);
            Z = str2num(app.ZEditField.Value);
            q = CalculoCI(X,Y,Z)
            
            q11 = q(1)
            q22 = q(2)
            q33 = q(3)
            app.Label_4.Text = num2str(q11);
            app.Label_5.Text = num2str(q22);
            app.Label_6.Text = num2str(q33);

        end

        % Button pushed function: GenerarlinealButton
        function GenerarlinealButtonPushed(app, event)
            %Conexion de arduino
            ard = arduino('COM6', 'Mega2560', 'Libraries', 'Servo');
            Servo1 = servo(ard, 'D13');
            Servo2 = servo(ard, 'D11');
            Servo3 = servo(ard, 'D10');
            
            %Obtencion de valores de interfaz
            x_pos = (app.X_val.Value);
            y_pos = (app.Y_val.Value);
            z_pos = (app.Z_val.Value);
            largo = app.EditField_20.Value;
            ancho = app.EditField_21.Value;
            tiempo_inicial = (app.EditField_22.Value);
            tiempo_final = (app.EditField_23.Value);
            
            %Calculo de puntos
            x_pos = x_pos + 19.5;
            z_pos = z_pos + 4;
            ptoA = [x_pos, y_pos, z_pos];
            ptoB = [x_pos, y_pos + largo, z_pos];
            ptoC = [x_pos, y_pos+largo, z_pos+ancho];
            ptoD = [x_pos, y_pos, z_pos+ancho];
            
            %Calculo de cinematica Inversa
            qA = app.CalculoCI(ptoA(1), ptoA(2), ptoA(3));
            qB= app.CalculoCI(ptoB(1), ptoB(2), ptoB(3));
            qC = app.CalculoCI(ptoC(1), ptoC(2), ptoC(3));
            qD = app.CalculoCI(ptoD(1), ptoD(2), ptoD(3));
            dt = 0.2;
            %q_general = [trayectoria1, trayectoria2, trayectoria3, trayectoria4];
            
           % Trayectoria1 = app.Interpol_lin(tiempo_inicial, tiempo_final, ptoA)
            
           %Calcula las trayectoris usando interpolacion lineal
           [t1, q1A] = app.Interpol_lin(tiempo_inicial,tiempo_final,qA(1), qB(1),dt);
           [t2, q2A] = app.Interpol_lin(tiempo_inicial,tiempo_final,qA(2), qB(2),dt);
           [t3, q3A] = app.Interpol_lin(tiempo_inicial,tiempo_final,qA(3), qB(3),dt);
           
           [t11, q11] = app.Interpol_lin(tiempo_inicial,tiempo_final,qB(1), qC(1),dt);
           [t22, q22] = app.Interpol_lin(tiempo_inicial,tiempo_final,qB(2), qC(2),dt);
           [t33, q33] = app.Interpol_lin(tiempo_inicial,tiempo_final,qB(3), qC(3),dt);    
           
           [t111, q111] = app.Interpol_lin(tiempo_inicial,tiempo_final,qC(1), qD(1),dt);
           [t222, q222] = app.Interpol_lin(tiempo_inicial,tiempo_final,qC(2), qD(2),dt);
           [t333, q333] = app.Interpol_lin(tiempo_inicial,tiempo_final,qC(3), qD(3),dt);
           
           [t1111, q1111] = app.Interpol_lin(tiempo_inicial,tiempo_final,qD(1), qA(1),dt);
           [t2222, q2222] = app.Interpol_lin(tiempo_inicial,tiempo_final,qD(2), qA(2),dt);
           [t3333, q3333] = app.Interpol_lin(tiempo_inicial,tiempo_final,qD(3), qA(3),dt);
           
           
           %Envio de datos al servo y ploteo de robot
           for i = 1:length(q1A)
               %Calculo de grados a valor de servo
                deg1 = ((q1A(i))+90)/180; % valores de 0 a 1
                deg2 = (q2A(i))/180;
                deg3 = 1-(((q3A(i))+90)/180); % corrige el giro con 1 
                
                % Envio de posicion al servo
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                
                %Plotea robot (convierte grados a radianes)
                app.RobotArticulado.plot([deg2rad(q1A(i)), deg2rad(q2A(i)), deg2rad(q3A(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q11)
                deg1 = ((q11(i))+90)/180; % valores de 0 a 1
                deg2 = (q22(i))/180;
                deg3 = 1-(((q33(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q11(i)), deg2rad(q22(i)), deg2rad(q33(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q111)
                deg1 = ((q111(i))+90)/180; % valores de 0 a 1
                deg2 = (q222(i))/180;
                deg3 = 1-(((q333(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q111(i)), deg2rad(q222(i)), deg2rad(q333(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q1111)
                deg1 = ((q1111(i))+90)/180; % valores de 0 a 1
                deg2 = (q2222(i))/180;
                deg3 = 1-(((q3333(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1111(i)), deg2rad(q2222(i)), deg2rad(q3333(i))]); hold on
                pause(dt);
           end
        end

        % Button pushed function: GenerarcbicoButton
        function GenerarcbicoButtonPushed(app, event)
            ard = arduino('COM6', 'Mega2560', 'Libraries', 'Servo');
            Servo1 = servo(ard, 'D13');
            Servo2 = servo(ard, 'D11');
            Servo3 = servo(ard, 'D10');
            
            x_pos = (app.X_val.Value);
            y_pos = (app.Y_val.Value);
            z_pos = (app.Z_val.Value);
            largo = app.EditField_20.Value;
            ancho = app.EditField_21.Value;
            tiempo_inicial = app.EditField_28.Value;
            tiempo_final = app.EditField_29.Value;
            
            x_pos = x_pos + 19.5;
            z_pos = z_pos + 4;
            ptoA = [x_pos, y_pos, z_pos];
            ptoB = [x_pos, y_pos + largo, z_pos];
            ptoC = [x_pos, y_pos+largo, z_pos+ancho];
            ptoD = [x_pos, y_pos, z_pos+ancho];
            qA = app.CalculoCI(ptoA(1), ptoA(2), ptoA(3));
            qB= app.CalculoCI(ptoB(1), ptoB(2), ptoB(3));
            qC = app.CalculoCI(ptoC(1), ptoC(2), ptoC(3));
            qD = app.CalculoCI(ptoD(1), ptoD(2), ptoD(3));
            dt = 0.2
            
            [t1, q1_cub, qp1] = app.Interpol_cub(tiempo_inicial, tiempo_final, qA(1), qB(1), dt, 0, 0);
            [t2, q2_cub, qp2] = app.Interpol_cub(tiempo_inicial, tiempo_final, qA(2), qB(2), dt, 0, 0);
            [t3, q3_cub, qp3] = app.Interpol_cub(tiempo_inicial, tiempo_final, qA(3), qB(3), dt, 0, 0);
            
            [t11, q11_cub, qp11] = app.Interpol_cub(tiempo_inicial, tiempo_final, qB(1), qC(1), dt, 0, 0);
            [t22, q22_cub, qp22] = app.Interpol_cub(tiempo_inicial, tiempo_final, qB(2), qC(2), dt, 0, 0);
            [t33, q33_cub, qp33] = app.Interpol_cub(tiempo_inicial, tiempo_final, qB(3), qC(3), dt, 0, 0);
            
            [t111, q111_cub, qp111] = app.Interpol_cub(tiempo_inicial, tiempo_final, qC(1), qD(1), dt, 0, 0);
            [t222, q222_cub, qp222] = app.Interpol_cub(tiempo_inicial, tiempo_final, qC(2), qD(2), dt, 0, 0);
            [t333, q333_cub, qp333] = app.Interpol_cub(tiempo_inicial, tiempo_final, qC(3), qD(3), dt, 0, 0);
            
            [t1111, q1111_cub, qp1111] = app.Interpol_cub(tiempo_inicial, tiempo_final, qD(1), qA(1), dt, 0, 0);
            [t2222, q2222_cub, qp2222] = app.Interpol_cub(tiempo_inicial, tiempo_final, qD(2), qA(2), dt, 0, 0);
            [t3333, q3333_cub, qp3333] = app.Interpol_cub(tiempo_inicial, tiempo_final, qD(3), qA(3), dt, 0, 0);
            
            for i = 1:length(q1_cub)
                deg1 = ((q1_cub(i))+90)/180; % valores de 0 a 1
                deg2 = (q2_cub(i))/180;
                deg3 = 1-(((q3_cub(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1_cub(i)), deg2rad(q2_cub(i)), deg2rad(q3_cub(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q11_cub)
                deg1 = ((q11_cub(i))+90)/180; % valores de 0 a 1
                deg2 = (q22_cub(i))/180;
                deg3 = 1-(((q33_cub(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q11_cub(i)), deg2rad(q22_cub(i)), deg2rad(q33_cub(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q111_cub)
                deg1 = ((q111_cub(i))+90)/180; % valores de 0 a 1
                deg2 = (q222_cub(i))/180;
                deg3 = 1-(((q333_cub(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q111_cub(i)), deg2rad(q222_cub(i)), deg2rad(q333_cub(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q1111_cub)
                deg1 = ((q1111_cub(i))+90)/180; % valores de 0 a 1
                deg2 = (q2222_cub(i))/180;
                deg3 = 1-(((q3333_cub(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1111_cub(i)), deg2rad(q2222_cub(i)), deg2rad(q3333_cub(i))]); hold on
                pause(dt);
           end
        end

        % Button pushed function: GenerarLSPBButton
        function GenerarLSPBButtonPushed(app, event)
            ard = arduino('COM6', 'Mega2560', 'Libraries', 'Servo');
            Servo1 = servo(ard, 'D13');
            Servo2 = servo(ard, 'D11');
            Servo3 = servo(ard, 'D10');
            
            x_pos = (app.X_val.Value);
            y_pos = (app.Y_val.Value);
            z_pos = (app.Z_val.Value);
            largo = app.EditField_20.Value;
            ancho = app.EditField_21.Value;
            tiempo_final = app.EditField_30.Value;
            
            
            x_pos = x_pos + 19.5;
            z_pos = z_pos + 4;
            ptoA = [x_pos, y_pos, z_pos];
            ptoB = [x_pos, y_pos + largo, z_pos];
            ptoC = [x_pos, y_pos+largo, z_pos+ancho];
            ptoD = [x_pos, y_pos, z_pos+ancho];
            qA = app.CalculoCI(ptoA(1), ptoA(2), ptoA(3));
            qB= app.CalculoCI(ptoB(1), ptoB(2), ptoB(3));
            qC = app.CalculoCI(ptoC(1), ptoC(2), ptoC(3));
            qD = app.CalculoCI(ptoD(1), ptoD(2), ptoD(3));
            dt = 0.1;
            
            [q1_lspb, t1] = app.LSPB(qA(1), qB(1), tiempo_final, dt);
            [q2_lspb, t2] = app.LSPB(qA(2), qB(2), tiempo_final, dt);
            [q3_lspb, t3] = app.LSPB(qA(3), qB(3), tiempo_final, dt);
            
            [q11_lspb, t11] = app.LSPB(qB(1), qC(1), tiempo_final, dt);
            [q22_lspb, t22] = app.LSPB(qB(2), qC(2), tiempo_final, dt);
            [q33_lspb, t33] = app.LSPB(qB(3), qC(3), tiempo_final, dt);

            [q111_lspb, t111] = app.LSPB(qC(1), qD(1), tiempo_final, dt);
            [q222_lspb, t222] = app.LSPB(qC(2), qD(2), tiempo_final, dt);
            [q333_lspb, t333] = app.LSPB(qC(3), qD(3), tiempo_final, dt);
            
            [q1111_lspb, t1111] = app.LSPB(qD(1), qA(1), tiempo_final, dt);
            [q2222_lspb, t2222] = app.LSPB(qD(2), qA(2), tiempo_final, dt);
            [q3333_lspb, t3333] = app.LSPB(qD(3), qA(3), tiempo_final, dt);
            
                        
            for i = 1:length(q1_lspb)
                deg1 = ((q1_lspb(i))+90)/180; % valores de 0 a 1
                deg2 = (q2_lspb(i))/180;
                deg3 = 1-(((q3_lspb(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1_lspb(i)), deg2rad(q2_lspb(i)), deg2rad(q3_lspb(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q11_lspb)
                deg1 = ((q11_lspb(i))+90)/180; % valores de 0 a 1
                deg2 = (q22_lspb(i))/180;
                deg3 = 1-(((q33_lspb(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q11_lspb(i)), deg2rad(q22_lspb(i)), deg2rad(q33_lspb(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q111_lspb)
                deg1 = ((q111_lspb(i))+90)/180; % valores de 0 a 1
                deg2 = (q222_lspb(i))/180;
                deg3 = 1-(((q333_lspb(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q111_lspb(i)), deg2rad(q222_lspb(i)), deg2rad(q333_lspb(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q1111_lspb)
                deg1 = ((q1111_lspb(i))+90)/180; % valores de 0 a 1
                deg2 = (q2222_lspb(i))/180;
                deg3 = 1-(((q3333_lspb(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1111_lspb(i)), deg2rad(q2222_lspb(i)), deg2rad(q3333_lspb(i))]); hold on
                pause(dt);
           end
            
        end

        % Button pushed function: GenerarQuinticoButton
        function GenerarQuinticoButtonPushed(app, event)
            ard = arduino('COM6', 'Mega2560', 'Libraries', 'Servo');
            Servo1 = servo(ard, 'D13');
            Servo2 = servo(ard, 'D11');
            Servo3 = servo(ard, 'D10');
            
            x_pos = (app.X_val.Value);
            y_pos = (app.Y_val.Value);
            z_pos = (app.Z_val.Value);
            largo = app.EditField_20.Value;
            ancho = app.EditField_21.Value;
            tiempo_final = app.EditField_26.Value;
            tiempo_inicial = app.EditField_25.Value;
            
            x_pos = x_pos + 19.5;
            z_pos = z_pos + 4;
            ptoA = [x_pos, y_pos, z_pos];
            ptoB = [x_pos, y_pos + largo, z_pos];
            ptoC = [x_pos, y_pos+largo, z_pos+ancho];
            ptoD = [x_pos, y_pos, z_pos+ancho];
            qA = app.CalculoCI(ptoA(1), ptoA(2), ptoA(3));
            qB= app.CalculoCI(ptoB(1), ptoB(2), ptoB(3));
            qC = app.CalculoCI(ptoC(1), ptoC(2), ptoC(3));
            qD = app.CalculoCI(ptoD(1), ptoD(2), ptoD(3));
            dt = 0.1;
            
            [t1, q1_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qA(1), qB(1), dt, 0, 0, 0, 0);
            [t2, q2_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qA(2), qB(2), dt, 0, 0, 0, 0);
            [t3, q3_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qA(3), qB(3), dt, 0, 0, 0, 0);
            
            [t11, q11_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qB(1), qC(1), dt, 0, 0, 0, 0);
            [t22, q22_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qB(2), qC(2), dt, 0, 0, 0, 0);
            [t33, q33_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qB(3), qC(3), dt, 0, 0, 0, 0);
            
            [t111, q111_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qC(1), qD(1), dt, 0, 0, 0, 0);
            [t222, q222_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qC(2), qD(2), dt, 0, 0, 0, 0);
            [t333, q333_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qC(3), qD(3), dt, 0, 0, 0, 0);
            
            [t1111, q1111_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qD(1), qA(1), dt, 0, 0, 0, 0);
            [t2222, q2222_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qD(2), qA(2), dt, 0, 0, 0, 0);
            [t3333, q3333_quin] = app.Interpol_quin(tiempo_inicial, tiempo_final, qD(3), qA(3), dt, 0, 0, 0, 0);
                                      
            for i = 1:length(q1_quin)
                deg1 = ((q1_quin(i))+90)/180; % valores de 0 a 1
                deg2 = (q2_quin(i))/180;
                deg3 = 1-(((q3_quin(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1_quin(i)), deg2rad(q2_quin(i)), deg2rad(q3_quin(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q11_quin)
                deg1 = ((q11_quin(i))+90)/180; % valores de 0 a 1
                deg2 = (q22_quin(i))/180;
                deg3 = 1-(((q33_quin(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q11_quin(i)), deg2rad(q22_quin(i)), deg2rad(q33_quin(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q111_quin)
                deg1 = ((q111_quin(i))+90)/180; % valores de 0 a 1
                deg2 = (q222_quin(i))/180;
                deg3 = 1-(((q333_quin(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q111_quin(i)), deg2rad(q222_quin(i)), deg2rad(q333_quin(i))]); hold on
                pause(dt);
           end
           for i = 1:length(q1111_quin)
                deg1 = ((q1111_quin(i))+90)/180; % valores de 0 a 1
                deg2 = (q2222_quin(i))/180;
                deg3 = 1-(((q3333_quin(i))+90)/180); % corrige el giro con 1 -
                writePosition(Servo1, deg1);
                writePosition(Servo2, deg2);
                writePosition(Servo3, deg3);
                app.RobotArticulado.plot([deg2rad(q1111_quin(i)), deg2rad(q2222_quin(i)), deg2rad(q3333_quin(i))]); hold on
                pause(dt);
           end
        end

        % Callback function
        function GenerargraficaButtonPushed(app, event)
            for i = 1:100
               
                x = sin(i);
                plot(app.Grafica_Torque, [1 1 -2 -2],'-r');
                hold(app.Grafica_Torque);
               
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Servo1SliderLabel = uilabel(app.UIFigure);
            app.Servo1SliderLabel.Text = 'Servo 1';
            app.Servo1Slider = uislider(app.UIFigure);
            app.Servo1Slider.Limits = [-90 90];
            app.Servo1Slider.ValueChangedFcn = createCallbackFcn(app, @Servo1SliderValueChanged, true);
            app.VALLabel = uilabel(app.UIFigure);
            app.VALLabel.Text = 'VAL';
            app.Servo2SliderLabel = uilabel(app.UIFigure);
            app.Servo2SliderLabel.Text = 'Servo 2';
            app.Servo2Slider = uislider(app.UIFigure);
            app.Servo2Slider.Limits = [0 180];
            app.Servo2Slider.ValueChangedFcn = createCallbackFcn(app, @Servo2SliderValueChanged, true);
            app.VAL2Label = uilabel(app.UIFigure);
            app.VAL2Label.Text = 'VAL2';
            app.VAL3Label = uilabel(app.UIFigure);
            app.VAL3Label.Text = 'VAL3';
            app.ENVIARButton = uibutton(app.UIFigure, 'push');
            app.ENVIARButton.Text = 'ENVIAR!';
            app.ENVIARButton_2 = uibutton(app.UIFigure, 'push');
            app.ENVIARButton_2.ButtonPushedFcn = createCallbackFcn(app, @ENVIARButton_2Pushed, true);
            app.ENVIARButton_2.Text = 'ENVIAR!';
            app.JacobianoLabel = uilabel(app.UIFigure);
            app.JacobianoLabel.Text = 'Jacobiano:';
            app.CinematicadirectaLabel = uilabel(app.UIFigure);
            app.CinematicadirectaLabel.Text = {'Cinematica directa'; ''};
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'0'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.THETA1EditFieldLabel = uilabel(app.UIFigure);
            app.THETA1EditFieldLabel.Text = 'THETA 1';
            app.THETA1EditField = uieditfield(app.UIFigure, 'text');
            app.THETA1EditField.Value = '0';
            app.CalcularCDButton = uibutton(app.UIFigure, 'push');
            app.CalcularCDButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCDButtonPushed, true);
            app.CalcularCDButton.Text = {'Calcular CD'; ''};
            app.CalcularCIButton = uibutton(app.UIFigure, 'push');
            app.CalcularCIButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCIButtonPushed, true);
            app.CalcularCIButton.Text = {'Calcular CI'; ''};
            app.centrodemasapieza2Label = uilabel(app.UIFigure);
            app.centrodemasapieza2Label.Text = 'centro de masa pieza 2: ';
            app.centrodemasapieza3Label = uilabel(app.UIFigure);
            app.centrodemasapieza3Label.Text = 'centro de masa pieza 3: ';
            app.centrodemasapieza4Label = uilabel(app.UIFigure);
            app.centrodemasapieza4Label.Text = 'centro de masa pieza 4: ';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '(0.41,2.23,2.5)';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '(5.85,0.17,0.17)';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '(2.69,0.01,-1.51)';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '';
            app.InicioLabel = uilabel(app.UIFigure);
            app.InicioLabel.Text = 'Inicio:';
            app.LargoLabel = uilabel(app.UIFigure);
            app.LargoLabel.Text = 'Largo:';
            app.AnchoLabel = uilabel(app.UIFigure);
            app.AnchoLabel.Text = 'Ancho:';
            app.X_val = uieditfield(app.UIFigure, 'numeric');
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.RectnguloLabel = uilabel(app.UIFigure);
            app.RectnguloLabel.Text = 'Rectángulo:';
            app.LinealLabel = uilabel(app.UIFigure);
            app.LinealLabel.Text = 'Lineal:';
            app.EditField_22 = uieditfield(app.UIFigure, 'numeric');
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To:';
            app.TfLabel_3 = uilabel(app.UIFigure);
            app.TfLabel_3.Text = 'Tf:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.VALLabel.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.EditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.TfLabel = uilabel(app.UIFigure);
            app.TfLabel.Text = 'Tf:';
            app.TfLabel_2 = uilabel(app.UIFigure);
            app.TfLabel_2.Text = 'Tf:';
            app.EditField_23 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_28 = uieditfield(app.UIFigure, 'numeric');
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '0';
            app.QuinticoLabel = uilabel(app.UIFigure);
            app.QuinticoLabel.Text = 'Quintico';
            app.Servo3SliderLabel = uilabel(app.UIFigure);
            app.Servo3SliderLabel.Text = 'Servo 3';
            app.EditField_25 = uieditfield(app.UIFigure, 'numeric');
            app.TfLabel_4 = uilabel(app.UIFigure);
            app.TfLabel_4.Text = 'Tf:';
            app.LSPBLabel = uilabel(app.UIFigure);
            app.LSPBLabel.Text = 'LSPB';
            app.EditField_26 = uieditfield(app.UIFigure, 'numeric');
            app.GenerarcbicoButton = uibutton(app.UIFigure, 'push');
            app.GenerarcbicoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarcbicoButtonPushed, true);
            app.GenerarcbicoButton.Text = 'Generar cúbico';
            app.GenerarLSPBButton = uibutton(app.UIFigure, 'push');
            app.GenerarLSPBButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarLSPBButtonPushed, true);
            app.GenerarLSPBButton.Text = 'Generar LSPB';
            app.GenerarQuinticoButton = uibutton(app.UIFigure, 'push');
            app.GenerarQuinticoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarQuinticoButtonPushed, true);
            app.GenerarQuinticoButton.Text = 'Generar Quintico';
            app.GenerarlinealButton = uibutton(app.UIFigure, 'push');
            app.GenerarlinealButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarlinealButtonPushed, true);
            app.GenerarlinealButton.Text = 'Generar lineal';
            app.Y_val = uieditfield(app.UIFigure, 'numeric');
            app.Z_val = uieditfield(app.UIFigure, 'numeric');
            app.ToLabel_3 = uilabel(app.UIFigure);
            app.ToLabel_3.Text = 'To:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '0';
            app.CbicoLabel = uilabel(app.UIFigure);
            app.CbicoLabel.Text = 'Cúbico:';
            app.Servo3Slider = uislider(app.UIFigure);
            app.Servo3Slider.Limits = [-90 90];
            app.Servo3Slider.ValueChangedFcn = createCallbackFcn(app, @Servo3SliderValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'0'; ''};
            app.THETA2EditFieldLabel = uilabel(app.UIFigure);
            app.THETA2EditFieldLabel.Text = 'THETA 2';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.THETA3Label = uilabel(app.UIFigure);
            app.THETA3Label.Text = 'THETA 3:';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.THETA2EditField = uieditfield(app.UIFigure, 'text');
            app.THETA2EditField.Value = '0';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_15 = uieditfield(app.UIFigure, 'text');
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.EditField_18 = uieditfield(app.UIFigure, 'text');
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_17 = uieditfield(app.UIFigure, 'text');
            app.ConstantesLabel = uilabel(app.UIFigure);
            app.ConstantesLabel.Text = 'Constantes:';
            app.IxxLabel = uilabel(app.UIFigure);
            app.IxxLabel.Text = 'Ixx:';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Pieza2Label = uilabel(app.UIFigure);
            app.Pieza2Label.Text = 'Pieza 2:';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '502.04';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '887.64';
            app.EditField_13 = uieditfield(app.UIFigure, 'text');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '1033.23';
            app.IyyLabel = uilabel(app.UIFigure);
            app.IyyLabel.Text = 'Iyy:';
            app.IzzLabel_2 = uilabel(app.UIFigure);
            app.IzzLabel_2.Text = 'Izz:';
            app.IzzLabel = uilabel(app.UIFigure);
            app.IzzLabel.Text = 'Izz:';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '1150.5';
            app.EditField_16 = uieditfield(app.UIFigure, 'text');
            app.IyyLabel_2 = uilabel(app.UIFigure);
            app.IyyLabel_2.Text = 'Iyy:';
            app.THETA2Label = uilabel(app.UIFigure);
            app.THETA2Label.Text = 'THETA 2:';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '1101.14';
            app.THETA3EditFieldLabel = uilabel(app.UIFigure);
            app.THETA3EditFieldLabel.Text = 'THETA 3';
            app.IzzLabel_3 = uilabel(app.UIFigure);
            app.IzzLabel_3.Text = 'Izz:';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '92.39';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '957.66';
            app.THETA1Label = uilabel(app.UIFigure);
            app.THETA1Label.Text = 'THETA 1:';
            app.IyyLabel_3 = uilabel(app.UIFigure);
            app.IyyLabel_3.Text = 'Iyy:';
            app.Pieza3Label = uilabel(app.UIFigure);
            app.Pieza3Label.Text = 'Pieza 3:';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '835.71';
            app.THETA3EditField = uieditfield(app.UIFigure, 'text');
            app.THETA3EditField.Value = '0';
            app.masaLabel = uilabel(app.UIFigure);
            app.masaLabel.Text = 'masa:';
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = '100 g';
            app.gLabel_2 = uilabel(app.UIFigure);
            app.gLabel_2.Text = '90 g';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '275.38';
            app.gLabel_3 = uilabel(app.UIFigure);
            app.gLabel_3.Text = '64.37 g';
            app.masaLabel_2 = uilabel(app.UIFigure);
            app.masaLabel_2.Text = 'masa:';
            app.masaLabel_3 = uilabel(app.UIFigure);
            app.masaLabel_3.Text = 'masa:';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Pieza4Label = uilabel(app.UIFigure);
            app.Pieza4Label.Text = 'Pieza 4:';
            app.CinematicainversaLabel = uilabel(app.UIFigure);
            app.CinematicainversaLabel.Text = {'Cinematica inversa'; ''};
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '';
            app.IxxLabel_2 = uilabel(app.UIFigure);
            app.IxxLabel_2.Text = 'Ixx:';
            app.IxxLabel_3 = uilabel(app.UIFigure);
            app.IxxLabel_3.Text = 'Ixx:';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.XEditField.Value = '0';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.YEditField.Value = '0';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_robotf_mec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end